classdef Autopatcher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ArdunioResponseEditFieldLabel   matlab.ui.control.Label
        ArdunioResponseEditField        matlab.ui.control.EditField
        PressureRegulator1COMEditField  matlab.ui.control.EditField
        PressureRegulator2COMEditField  matlab.ui.control.EditField
        PressureRegulator3COMEditField  matlab.ui.control.EditField
        PressureRegulator4COMEditField  matlab.ui.control.EditField
        Channel1ButtonGroup             matlab.ui.container.ButtonGroup
        HighPressureButton_1            matlab.ui.control.ToggleButton
        LowPressureButton_1             matlab.ui.control.ToggleButton
        BreakInButton_1                 matlab.ui.control.ToggleButton
        SealButton_1                    matlab.ui.control.ToggleButton
        AtmosphereButton_1              matlab.ui.control.ToggleButton
        Clean1Button                    matlab.ui.control.Button
        Channel1Label                   matlab.ui.control.Label
        Channel2ButtonGroup             matlab.ui.container.ButtonGroup
        HighPressureButton_2            matlab.ui.control.ToggleButton
        LowPressureButton_2             matlab.ui.control.ToggleButton
        BreakInButton_2                 matlab.ui.control.ToggleButton
        SealButton_2                    matlab.ui.control.ToggleButton
        AtmosphereButton_2              matlab.ui.control.ToggleButton
        Clean2Button                    matlab.ui.control.Button
        Channel2Label                   matlab.ui.control.Label
        Channel3ButtonGroup             matlab.ui.container.ButtonGroup
        HighPressureButton_3            matlab.ui.control.ToggleButton
        LowPressureButton_3             matlab.ui.control.ToggleButton
        BreakInButton_3                 matlab.ui.control.ToggleButton
        SealButton_3                    matlab.ui.control.ToggleButton
        AtmosphereButton_3              matlab.ui.control.ToggleButton
        Clean3Button                    matlab.ui.control.Button
        Channel3Label                   matlab.ui.control.Label
        Channel4ButtonGroup             matlab.ui.container.ButtonGroup
        HighPressureButton_4            matlab.ui.control.ToggleButton
        LowPressureButton_4             matlab.ui.control.ToggleButton
        BreakInButton_4                 matlab.ui.control.ToggleButton
        SealButton_4                    matlab.ui.control.ToggleButton
        AtmosphereButton_4              matlab.ui.control.ToggleButton
        Clean4Button                    matlab.ui.control.Button
        Channel4Label                   matlab.ui.control.Label
        ConnectedCOMChannel1            matlab.ui.control.Lamp
        ConnectedCOMChannel2            matlab.ui.control.Lamp
        ConnectedCOMChannel3            matlab.ui.control.Lamp
        ConnectedCOMChannel4            matlab.ui.control.Lamp
        HighPressureEditField           matlab.ui.control.NumericEditField
        LowPressureEditField            matlab.ui.control.NumericEditField
        mbarLabel_3                     matlab.ui.control.Label
        mbarLabel_4                     matlab.ui.control.Label
        SealPressureEditField           matlab.ui.control.NumericEditField
        BreakInPressureEditField        matlab.ui.control.NumericEditField
        mbarLabel_2                     matlab.ui.control.Label
        mbarLabel                       matlab.ui.control.Label
        BreakInTimeEditField            matlab.ui.control.NumericEditField
        msLabel_2                       matlab.ui.control.Label
        PressureLabel                   matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        SolenoidControllerIndicator     matlab.ui.control.Lamp
        PositionsTable                  matlab.ui.control.Table
        SetSafeButton_Ch1               matlab.ui.control.Button
        SetCleanButton_Ch1              matlab.ui.control.Button
        SetSafeButton_Ch2               matlab.ui.control.Button
        SetCleanButton_Ch2              matlab.ui.control.Button
        ControllerEditFieldLabel        matlab.ui.control.Label
        ControllerEditField             matlab.ui.control.EditField
        SetSafeButton_Ch3               matlab.ui.control.Button
        SetCleanButton_Ch3              matlab.ui.control.Button
        SetSafeButton_Ch4               matlab.ui.control.Button
        SetCleanButton_Ch4              matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ElectrodeDevice_Ch1             matlab.ui.control.EditField
        PrimaryChannel_Ch1              matlab.ui.control.EditField
        SecondaryChannel_Ch1            matlab.ui.control.EditField
        CommandChannel_Ch1              matlab.ui.control.EditField
        PrimaryChannel_Ch2              matlab.ui.control.EditField
        SecondaryChannel_Ch2            matlab.ui.control.EditField
        CommandChannel_Ch2              matlab.ui.control.EditField
        PrimaryChannel_Ch3              matlab.ui.control.EditField
        SecondaryChannel_Ch3            matlab.ui.control.EditField
        CommandChannel_Ch3              matlab.ui.control.EditField
        PrimaryChannel_Ch4              matlab.ui.control.EditField
        SecondaryChannel_Ch4            matlab.ui.control.EditField
        CommandChannel_Ch4              matlab.ui.control.EditField
        PrimaryLabel                    matlab.ui.control.Label
        SecondLabel                     matlab.ui.control.Label
        CMDLabel                        matlab.ui.control.Label
        DeviceLabel                     matlab.ui.control.Label
        ElectrodeDevice_Ch2             matlab.ui.control.EditField
        ElectrodeDevice_Ch3             matlab.ui.control.EditField
        ElectrodeDevice_Ch4             matlab.ui.control.EditField
    end

    
    properties (Access = private)
        manipulator
        manipulator_positions
        Channel1State % State of Channel 1
        Channel2State % State of Channel 2
        Channel3State % State of Channel 3
        Channel4State %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED % State of Channel 4
        ProportionAirModbusObjectChannel1 % Modbus Object for Channel 1
        ProportionAirModbusObjectChannel2 % Modbus Object for Channel 2
        ProportionAirModbusObjectChannel3 % Modbus Object for Channel 3
        ProportionAirModbusObjectChannel4 % Modbus Object for Channel 4
        SolenoidDriverObjectChannel % Serial Connection Object for Solenoid Driver
        modbusCMDRegister = 50 % Command Register for writing desired pressure values
        modbusPressureRegister = 49 % Register that holds the current internal pressure measurement value
        modbusServerId = 247 % Server ID for ProportionAir Modbus
        baudRate = 115200;
        parity = 'even';
        timeout = 0.1;
    end
    
    methods (Static, Access = private)
        
        function registerValue = convertPressure(pressureValue)
            registerValue = double(floor(39.21*pressureValue + 27041));
            
            if registerValue > 65535
                registerValue = 65535;
            elseif registerValue < 0
                registerValue = 0;
            end
        end


        function closeAndClearAllConnectionsToPort(serialPortName)
            %close and clear all connections to the serial port
            openPorts = instrfind('Port', serialPortName, 'Status', 'open');
            if ~isempty(openPorts)
                fclose(openPorts);
            end
            delete(instrfind('Port', serialPortName)); 
        end
    end


    methods (Access = private)
        
        function status = writeModbusCMD(app, COMChannel, registerValue)  
            try
                write(COMChannel, 'holdingregs', app.modbusCMDRegister, registerValue, app.modbusServerId, 'uint16');
                
                if COMChannel == app.ProportionAirModbusObjectChannel1
                    app.ConnectedCOMChannel1.Color = [0 1 0];
                elseif COMChannel == app.ProportionAirModbusObjectChannel2
                    app.ConnectedCOMChannel2.Color = [0 1 0];
                elseif COMChannel == app.ProportionAirModbusObjectChannel3
                    app.ConnectedCOMChannel3.Color = [0 1 0];    
                elseif COMChannel == app.ProportionAirModbusObjectChannel4
                    app.ConnectedCOMChannel4.Color = [0 1 0];
                end
                
                status = true;
            
            catch
                if COMChannel == app.ProportionAirModbusObjectChannel1
                    app.ConnectedCOMChannel1.Color = [1 0 0];
                elseif COMChannel == app.ProportionAirModbusObjectChannel2
                    app.ConnectedCOMChannel2.Color = [1 0 0];
                elseif COMChannel == app.ProportionAirModbusObjectChannel3
                    app.ConnectedCOMChannel3.Color = [1 0 0];    
                elseif COMChannel == app.ProportionAirModbusObjectChannel4
                    app.ConnectedCOMChannel4.Color = [1 0 0];
                end
                
                status = false;
            end
        end


        function m = connectToModbus(app, COMChannel)
                
            m = proportionAirModbus('serialrtu', COMChannel, 'BaudRate', app.baudRate, 'Parity', app.parity, 'Timeout', app.timeout);
        end


        function arduinoWrite(app, CMD)
           fwrite(app.SolenoidDriverObjectChannel, CMD);
           
           set(app.ArdunioResponseEditField, 'Value', fgetl(app.SolenoidDriverObjectChannel));
        end


        function registerValue = readModbusPressure(app, COMChannel)
            registerValue = read(COMChannel,'holdingregs', app.modbusPressureRegister, 1, app.modbusServerId, 'uint16');
        end


        function results = genericChannelButtonGroupSelectionChanged(app, channel)
            % need to make generic channel button group selection changed handler
        end
        
        function results = updatePositionsTable(app)
            app.PositionsTable.Data = [cellstr(num2str(app.manipulator_positions(1:2:end,:))) cellstr(num2str(app.manipulator_positions(2:2:end,:)))];
        end
        
        function moveManipulatorToLocationRelativeToOrigin(app, original_position, position)
            %move to a random location
            app.manipulator.SM5GoToPositionAbs(4,position(1)+original_position(4), 'Fast', false);
            app.manipulator.SM5GoToPositionAbs(5,position(2)+original_position(5), 'Fast', false);
            app.manipulator.SM5GoToPositionAbs(6,position(3)+original_position(6), 'Fast', false);
            pause(0.2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load the last positions for the manipulators
            % connect to the manipulators
            
            app.manipulator = SM5(3, 2); % COM port, deviceNumber
            
            app.manipulator_positions = zeros(4,6);
            
            app.resistance.daq = daq("ni");
            app.resistance.daq.Rate = 50000;
        end

        % Selection changed function: Channel1ButtonGroup
        function Channel1ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Channel1ButtonGroup.SelectedObject;

            switch selectedButton
                case app.HighPressureButton_1
                    pressure = app.HighPressureEditField.Value;
                    switchString = 'pressure 1';
                case app.LowPressureButton_1
                    pressure = app.LowPressureEditField.Value;
                    switchString = 'pressure 1';
                case app.AtmosphereButton_1
                    pressure = 0;
                    switchString = 'atm 1';
                case app.SealButton_1
                    pressure = app.SealPressureEditField.Value;
                    switchString = 'pressure 1';
                case app.BreakInButton_1
                    if app.Channel1State ~= app.AtmosphereButton_1
                        % switch to atmosphere if that wasn't the previous
                        % state
                        pressure = 0;
                        switchString = 'atm 1';
                        selectedButton = app.AtmosphereButton_1;
                    else
                        % if atmosphere was the previous state
                        pressure = app.BreakInPressureEditField.Value;
                        switchString = ['breakin 1 ' num2str(app.BreakInTimeEditField.Value)];
                    end
            end
            
            COMChannel = app.ProportionAirModbusObjectChannel1;
            registerValue = app.convertPressure(pressure);
            
            if app.writeModbusCMD(COMChannel, registerValue)
                pause(.1);
                app.arduinoWrite(switchString);
                if strcmp(switchString(1:3), 'bre') 
                    pause(app.BreakInTimeEditField.Value/1000); %wait for the break-in time
                    app.Channel1State = app.AtmosphereButton_1; %set the state back to atmosphere
                else
                    app.Channel1State = selectedButton;
                end
            end
            app.Channel1ButtonGroup.set('SelectedObject', app.Channel1State); %revert the button group to the previous state on error
        end

        % Value changed function: HighPressureEditField
        function HighPressureEditFieldValueChanged(app, event)
            pressure = app.HighPressureEditField.Value;
            registerValue = app.convertPressure(pressure);
            
            if(app.Channel1ButtonGroup.SelectedObject == app.HighPressureButton_1)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel1, registerValue);
            end
            
            if(app.Channel2ButtonGroup.SelectedObject == app.HighPressureButton_2)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel2, registerValue);
            end
            
            if(app.Channel3ButtonGroup.SelectedObject == app.HighPressureButton_3)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel3, registerValue);
            end
            
            if(app.Channel4ButtonGroup.SelectedObject == app.HighPressureButton_4)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel4, registerValue);
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)

            app.Channel1State = app.HighPressureButton_1; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
            app.Channel2State = app.HighPressureButton_2; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
            app.Channel3State = app.HighPressureButton_3; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
            app.Channel4State = app.HighPressureButton_4; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
            
            % blank all of the lamps so we know when things have changed
            app.ConnectedCOMChannel1.Color = [0.47,0.67,0.19];
            app.ConnectedCOMChannel2.Color = [0.47,0.67,0.19];
            app.ConnectedCOMChannel3.Color = [0.47,0.67,0.19];
            app.ConnectedCOMChannel4.Color = [0.47,0.67,0.19];
            app.SolenoidControllerIndicator.Color = [0.47,0.67,0.19];
            
            % TRY TO CONNECT TO THE MODBUS SERVER AT THE SPECIFIED ADDRESS FOR CHANNEL 1
            try
                serialPortName = app.PressureRegulator1COMEditField.Value;
                
                app.closeAndClearAllConnectionsToPort(serialPortName);
                
                app.ProportionAirModbusObjectChannel1 = app.connectToModbus(serialPortName);
                app.readModbusPressure(app.ProportionAirModbusObjectChannel1);
                app.Channel1State = app.HighPressureButton_1; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
                app.ConnectedCOMChannel1.Color = [0 1 0];
            catch error
                disp(error);
                app.ConnectedCOMChannel1.Color = [1 0 0];
            end
            
            % TRY TO CONNECT TO THE MODBUS SERVER AT THE SPECIFIED ADDRESS FOR CHANNEL 2
            try
                serialPortName = app.PressureRegulator2COMEditField.Value;
                
                app.closeAndClearAllConnectionsToPort(serialPortName);
                
                app.ProportionAirModbusObjectChannel2 = app.connectToModbus(serialPortName);
                app.readModbusPressure(app.ProportionAirModbusObjectChannel2);
                app.Channel2State = app.HighPressureButton_2; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
                app.ConnectedCOMChannel2.Color = [0 1 0];
            catch
                app.ConnectedCOMChannel2.Color = [1 0 0];
            end
            
            % TRY TO CONNECT TO THE MODBUS SERVER AT THE SPECIFIED ADDRESS FOR CHANNEL 3
            try
                serialPortName = app.PressureRegulator3COMEditField.Value;
                
                app.closeAndClearAllConnectionsToPort(serialPortName);
                
                app.ProportionAirModbusObjectChannel3 = app.connectToModbus(serialPortName);
                app.readModbusPressure(app.ProportionAirModbusObjectChannel3);
                app.Channel3State = app.HighPressureButton_3; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
                app.ConnectedCOMChannel3.Color = [0 1 0];
            catch
                app.ConnectedCOMChannel3.Color = [1 0 0];
            end
            
            % TRY TO CONNECT TO THE MODBUS SERVER AT THE SPECIFIED ADDRESS FOR CHANNEL 4
            try
                serialPortName = app.PressureRegulator4COMEditField.Value;
                
                app.closeAndClearAllConnectionsToPort(serialPortName);
                
                app.ProportionAirModbusObjectChannel4 = app.connectToModbus(serialPortName);
                app.readModbusPressure(app.ProportionAirModbusObjectChannel4);
                app.Channel4State = app.HighPressureButton_4; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
                app.ConnectedCOMChannel4.Color = [0 1 0];
            catch
                app.ConnectedCOMChannel4.Color = [1 0 0];
            end
            
            % TRY TO CONNECT TO THE ARDUINO
            try
                serialPortName = app.ControllerEditField.Value;
                
                app.closeAndClearAllConnectionsToPort(serialPortName);
                
                % create a connection to the serial port
                app.SolenoidDriverObjectChannel = serial(serialPortName, 'BaudRate', app.baudRate, 'Terminator', 'LF');
                fopen(app.SolenoidDriverObjectChannel);
                app.SolenoidControllerIndicator.Color = [0 1 0];
            catch error
                app.SolenoidControllerIndicator.Color = [1 0 0];
                %rethrow(error);
            end
            
            
            % TRY TO CONNECT TO THE DAQ FOR All Channels
            try
                % Channel 1
                app.resistance.ch1.primary = addinput(app.resistance.daq, app.ElectrodeDevice_Ch1, app.PrimaryChannel_Ch1, "Voltage");
                app.resistance.ch1.secondary = addinput(app.resistance.daq, app.ElectrodeDevice_Ch1, app.SecondaryChannel_Ch1, "Voltage");
                app.resistance.ch1.command = addoutput(app.resistance.daq, app.ElectrodeDevice_Ch1, app.CommandChannel_Ch1, "Voltage");
                
                app.resistance.ch2.primary = addinput(app.resistance.daq, app.ElectrodeDevice_Ch2, app.PrimaryChannel_Ch2, "Voltage");
                app.resistance.ch2.secondary = addinput(app.resistance.daq, app.ElectrodeDevice_Ch2, app.SecondaryChannel_Ch2, "Voltage");
                app.resistance.ch2.command = addoutput(app.resistance.daq, app.ElectrodeDevice_Ch2, app.CommandChannel_Ch2, "Voltage");
                
                app.resistance.ch3.primary = addinput(app.resistance.daq, app.ElectrodeDevice_Ch3, app.PrimaryChannel_Ch3, "Voltage");
                app.resistance.ch3.secondary = addinput(app.resistance.daq, app.ElectrodeDevice_Ch3, app.SecondaryChannel_Ch3, "Voltage");
                app.resistance.ch3.command = addoutput(app.resistance.daq, app.ElectrodeDevice_Ch3, app.CommandChannel_Ch3, "Voltage");
                
                app.resistance.ch4.primary = addinput(app.resistance.daq, app.ElectrodeDevice_Ch4, app.PrimaryChannel_Ch4, "Voltage");
                app.resistance.ch4.secondary = addinput(app.resistance.daq, app.ElectrodeDevice_Ch4, app.SecondaryChannel_Ch4, "Voltage");
                app.resistance.ch4.command = addoutput(app.resistance.daq, app.ElectrodeDevice_Ch4, app.CommandChannel_Ch4, "Voltage");
                
                app.resistance.squarewave.frequency = 50; %Hz
                app.resistance.squarewave.amplitude = 10; %mV
                samplesPerSquarewave = floor(app.resistance.daq.Rate / app.resistance.squarewave.frequency); % samples per square wave
                signalData = zeros(samplesPerSquarewave,4);
                signalData(round(0.25*length(signalData)):round(0.75*signalData),:) = app.resistance.squarewave.amplitude;
                preload(d,signalData);
                
                start(d,"RepeatOutput");
                % Device output now repeated while MATLAB continues.
                
                pause(10);
                stop(d)
                
                disp(app.resistance);
            catch error
                rethrow(error);
            end
            
            
        end

        % Selection changed function: Channel2ButtonGroup
        function Channel2ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Channel2ButtonGroup.SelectedObject;

            switch selectedButton
                case app.HighPressureButton_2
                    pressure = app.HighPressureEditField.Value;
                    switchString = 'pressure 2';
                case app.LowPressureButton_2
                    pressure = app.LowPressureEditField.Value;
                    switchString = 'pressure 2';
                case app.AtmosphereButton_2
                    pressure = 0;
                    switchString = 'atm 2';
                case app.SealButton_2
                    pressure = app.SealPressureEditField.Value;
                    switchString = 'pressure 2';
                case app.BreakInButton_2
                    pressure = app.BreakInPressureEditField.Value;
                    switchString = ['breakin 2 ' num2str(app.BreakInTimeEditField.Value)];
            end
            
            COMChannel = app.ProportionAirModbusObjectChannel2;
            registerValue = app.convertPressure(pressure);
            
            if(app.writeModbusCMD(COMChannel, registerValue))
                pause(.1);
                app.arduinoWrite(switchString);
                if strcmp(switchString(1:3), 'bre')
                    % wait for return from arduino
                    %pause(app.BreakInTimeEditField.Value/1000);
                    app.Channel2ButtonGroup.set('SelectedObject', app.AtmosphereButton_2); %revert button group to atmosphere
                else
                    app.Channel2State = selectedButton;
                end
            else
                app.Channel2ButtonGroup.set('SelectedObject', app.Channel2State); %revert the button group to the previous state on error
            end
        end

        % Selection changed function: Channel3ButtonGroup
        function Channel3ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Channel3ButtonGroup.SelectedObject;

            switch selectedButton
                case app.HighPressureButton_3
                    pressure = app.HighPressureEditField.Value;
                    switchString = 'pressure 3';
                case app.LowPressureButton_3
                    pressure = app.LowPressureEditField.Value;
                    switchString = 'pressure 3';
                case app.AtmosphereButton_3
                    pressure = 0;
                    switchString = 'atm 3';
                case app.SealButton_3
                    pressure = app.SealPressureEditField.Value;
                    switchString = 'pressure 3';
                case app.BreakInButton_3
                    pressure = app.BreakInPressureEditField.Value;
                    switchString = ['breakin 3 ' num2str(app.BreakInTimeEditField.Value)];
            end
            
            COMChannel = app.ProportionAirModbusObjectChannel3;
            registerValue = app.convertPressure(pressure);
            
            if app.writeModbusCMD(COMChannel, registerValue)
                pause(.1);
                app.arduinoWrite(switchString);
                if strcmp(switchString(1:3), 'bre') 
                    pause(app.BreakInTimeEditField.Value/1000);
                    app.Channel3ButtonGroup.set('SelectedObject', app.AtmosphereButton_3); %revert button group to atmosphere
                else
                    app.Channel3State = selectedButton;
                end
            else
                app.Channel3ButtonGroup.set('SelectedObject', app.Channel3State); %revert the button group to the previous state on error
            end
        end

        % Selection changed function: Channel4ButtonGroup
        function Channel4ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Channel4ButtonGroup.SelectedObject;

            switch selectedButton
                case app.HighPressureButton_4
                    pressure = app.HighPressureEditField.Value;
                    switchString = 'pressure 4';
                case app.LowPressureButton_4
                    pressure = app.LowPressureEditField.Value;
                    switchString = 'pressure 4';
                case app.AtmosphereButton_4
                    pressure = 0;
                    switchString = 'atm 4';
                case app.SealButton_4
                    pressure = app.SealPressureEditField.Value;
                    switchString = 'pressure 4';
                case app.BreakInButton_4
                    pressure = app.BreakInPressureEditField.Value;
                    switchString = ['breakin 4 ' num2str(app.BreakInTimeEditField.Value)];
            end
            
            COMChannel = app.ProportionAirModbusObjectChannel4;
            registerValue = app.convertPressure(pressure);
            
            if app.writeModbusCMD(COMChannel, registerValue)
                pause(.1);
                app.arduinoWrite(switchString);
                if strcmp(switchString(1:3), 'bre') 
                    pause(app.BreakInTimeEditField.Value/1000);
                    app.Channel4ButtonGroup.set('SelectedObject', app.AtmosphereButton_4); %revert button group to atmosphere
                else
                    app.Channel4State = selectedButton;
                end
            else
                disp(app.Channel4State);
                %app.Channel4ButtonGroup.set('SelectedObject', app.Channel4State); %revert the button group to the previous state on error
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                fwrite(app.SolenoidDriverObjectChannel, 'atm 1');
                fwrite(app.SolenoidDriverObjectChannel, 'atm 2');
                fwrite(app.SolenoidDriverObjectChannel, 'atm 3');
                fwrite(app.SolenoidDriverObjectChannel, 'atm 4');
                
                fclose(app.SolenoidDriverObjectChannel);
            end
            
            % remove daq channels
            removechannel(app.resistance.daq,[1:length(app.resistance.daq.Channels)]);
            
            clear app.ProportionAirModbusObjectChannel1 app.ProportionAirModbusObjectChannel2 app.ProportionAirModbusObjectChannel3 app.ProportionAirModbusObjectChannel4 app.SolenoidDriverObjectChannel;
            delete(app)
            
        end

        % Value changed function: LowPressureEditField
        function LowPressureEditFieldValueChanged(app, event)
            pressure = app.LowPressureEditField.Value;
            registerValue = app.convertPressure(pressure);
            
            if(app.Channel1ButtonGroup.SelectedObject == app.LowPressureButton_1)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel1, registerValue);
            end
            
            if(app.Channel2ButtonGroup.SelectedObject == app.LowPressureButton_2)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel2, registerValue);
            end
            
            if(app.Channel3ButtonGroup.SelectedObject == app.LowPressureButton_3)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel3, registerValue);
            end
            
            if(app.Channel4ButtonGroup.SelectedObject == app.LowPressureButton_4)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel4, registerValue);
            end
        end

        % Value changed function: SealPressureEditField
        function SealPressureEditFieldValueChanged(app, event)
            pressure = app.SealPressureEditField.Value;
            registerValue = app.convertPressure(pressure);
            
            if(app.Channel1ButtonGroup.SelectedObject == app.SealButton_1)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel1, registerValue);
            end
            
            if(app.Channel2ButtonGroup.SelectedObject == app.SealButton_2)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel2, registerValue);
            end
            
            if(app.Channel3ButtonGroup.SelectedObject == app.SealButton_3)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel3, registerValue);
            end
            
            if(app.Channel4ButtonGroup.SelectedObject == app.SealButton_4)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel4, registerValue);
            end
        end

        % Value changed function: BreakInPressureEditField
        function BreakInPressureEditFieldValueChanged(app, event)
            pressure = app.BreakInPressureEditField.Value;
            registerValue = app.convertPressure(pressure);
            
            if(app.Channel1ButtonGroup.SelectedObject == app.BreakInButton_1)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel1, registerValue);
            end
            
            if(app.Channel2ButtonGroup.SelectedObject == app.BreakInButton_2)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel2, registerValue);
            end
            
            if(app.Channel3ButtonGroup.SelectedObject == app.BreakInButton_3)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel3, registerValue);
            end
            
            if(app.Channel4ButtonGroup.SelectedObject == app.BreakInButton_4)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel4, registerValue);
            end
        end

        % Button pushed function: SetSafeButton_Ch1
        function SetSafeButton_Ch1Pushed(app, event)
            app.manipulator_positions(1,:) = app.manipulator.positionAbsoluteRaw();
            app.updatePositionsTable();
        end

        % Button pushed function: SetCleanButton_Ch1
        function SetCleanButton_Ch1Pushed(app, event)
            app.manipulator_positions(2,:) = app.manipulator.positionAbsoluteRaw();
            app.updatePositionsTable();
        end

        % Button pushed function: Clean1Button
        function Clean1ButtonPushed(app, event)
            manipulator_original_position = app.manipulator.positionAbsoluteRaw();
            
            manipulator_safe_spot = app.manipulator_positions(1,:);
            manipulator_clean_bath = app.manipulator_positions(2,:);
            
            modBusChannel = app.ProportionAirModbusObjectChannel1;
            
            %calculate the register values required for cleaning
            lowPressureRegisterValue = app.convertPressure(app.LowPressureEditField.Value);
            highPressureRegisterValue = app.convertPressure(1000);
            suctionRegisterValue = app.convertPressure(-700);
            
            app.moveManipulatorToLocationRelativeToOrigin([0 0 0 manipulator_safe_spot(4) manipulator_original_position(5) manipulator_original_position(6)], [0 0 0 0 0 0]);
            pause(3)
            app.moveManipulatorToLocationRelativeToOrigin(manipulator_safe_spot, [0 0 0 0 0 0]);
            pause(1);
            
            app.arduinoWrite('pressure 1');
            
            app.moveManipulatorToLocationRelativeToOrigin(manipulator_clean_bath, [0 0 0 0 0 0]);
            pause(3);
            
            % clean
            for i = 1:5
                app.writeModbusCMD(modBusChannel, suctionRegisterValue);
                pause(0.9);
                app.writeModbusCMD(modBusChannel, highPressureRegisterValue);
                pause(1);
            end
            
            app.moveManipulatorToLocationRelativeToOrigin(manipulator_safe_spot, [0 0 0 0 0 0]);
            pause(3);
            
            manipulator_original_position(6) = manipulator_original_position(6) - 20; % move up 20 microns when we return
            
            % go back to low pressure (user defined)
            app.writeModbusCMD(modBusChannel, lowPressureRegisterValue);
            
            app.moveManipulatorToLocationRelativeToOrigin(manipulator_original_position, [0 0 0 0 0 0]);
            pause(5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ArdunioResponseEditFieldLabel = uilabel(app.UIFigure);
            app.ArdunioResponseEditFieldLabel.Text = 'Ardunio Response';
            app.ArdunioResponseEditField = uieditfield(app.UIFigure, 'text');
            app.PressureRegulator1COMEditField = uieditfield(app.UIFigure, 'text');
            app.PressureRegulator1COMEditField.Value = 'COM6';
            app.PressureRegulator2COMEditField = uieditfield(app.UIFigure, 'text');
            app.PressureRegulator2COMEditField.Value = 'COM7';
            app.PressureRegulator3COMEditField = uieditfield(app.UIFigure, 'text');
            app.PressureRegulator3COMEditField.Value = 'COM8';
            app.PressureRegulator4COMEditField = uieditfield(app.UIFigure, 'text');
            app.PressureRegulator4COMEditField.Value = 'COM9';
            app.Channel1ButtonGroup = uibuttongroup(app.UIFigure);
            app.Channel1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel1ButtonGroupSelectionChanged, true);
            app.HighPressureButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.HighPressureButton_1.Text = 'High Pressure';
            app.HighPressureButton_1.Value = true;
            app.LowPressureButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.LowPressureButton_1.Text = 'Low Pressure';
            app.BreakInButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.BreakInButton_1.Text = 'Break In';
            app.SealButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.SealButton_1.Text = 'Seal';
            app.AtmosphereButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.AtmosphereButton_1.Text = 'Atmosphere';
            app.Clean1Button = uibutton(app.Channel1ButtonGroup, 'push');
            app.Clean1Button.ButtonPushedFcn = createCallbackFcn(app, @Clean1ButtonPushed, true);
            app.Clean1Button.Text = 'Clean 1';
            app.Channel1Label = uilabel(app.UIFigure);
            app.Channel1Label.Text = 'Channel 1';
            app.Channel2ButtonGroup = uibuttongroup(app.UIFigure);
            app.Channel2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel2ButtonGroupSelectionChanged, true);
            app.HighPressureButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.HighPressureButton_2.Text = 'High Pressure';
            app.HighPressureButton_2.Value = true;
            app.LowPressureButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.LowPressureButton_2.Text = 'Low Pressure';
            app.BreakInButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.BreakInButton_2.Text = 'Break In';
            app.SealButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.SealButton_2.Text = 'Seal';
            app.AtmosphereButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.AtmosphereButton_2.Text = 'Atmosphere';
            app.Clean2Button = uibutton(app.Channel2ButtonGroup, 'push');
            app.Clean2Button.Text = 'Clean 2';
            app.Channel2Label = uilabel(app.UIFigure);
            app.Channel2Label.Text = 'Channel 2';
            app.Channel3ButtonGroup = uibuttongroup(app.UIFigure);
            app.Channel3ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel3ButtonGroupSelectionChanged, true);
            app.HighPressureButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.HighPressureButton_3.Text = 'High Pressure';
            app.HighPressureButton_3.Value = true;
            app.LowPressureButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.LowPressureButton_3.Text = 'Low Pressure';
            app.BreakInButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.BreakInButton_3.Text = 'Break In';
            app.SealButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.SealButton_3.Text = 'Seal';
            app.AtmosphereButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.AtmosphereButton_3.Text = 'Atmosphere';
            app.Clean3Button = uibutton(app.Channel3ButtonGroup, 'push');
            app.Clean3Button.Text = 'Clean 3';
            app.Channel3Label = uilabel(app.UIFigure);
            app.Channel3Label.Text = 'Channel 3';
            app.Channel4ButtonGroup = uibuttongroup(app.UIFigure);
            app.Channel4ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel4ButtonGroupSelectionChanged, true);
            app.HighPressureButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.HighPressureButton_4.Text = 'High Pressure';
            app.HighPressureButton_4.Value = true;
            app.LowPressureButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.LowPressureButton_4.Text = 'Low Pressure';
            app.BreakInButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.BreakInButton_4.Text = 'Break In';
            app.SealButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.SealButton_4.Text = 'Seal';
            app.AtmosphereButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.AtmosphereButton_4.Text = 'Atmosphere';
            app.Clean4Button = uibutton(app.Channel4ButtonGroup, 'push');
            app.Clean4Button.Text = 'Clean 4';
            app.Channel4Label = uilabel(app.UIFigure);
            app.Channel4Label.Text = 'Channel 4';
            app.ConnectedCOMChannel1 = uilamp(app.UIFigure);
            app.ConnectedCOMChannel2 = uilamp(app.UIFigure);
            app.ConnectedCOMChannel3 = uilamp(app.UIFigure);
            app.ConnectedCOMChannel4 = uilamp(app.UIFigure);
            app.HighPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighPressureEditField.ValueChangedFcn = createCallbackFcn(app, @HighPressureEditFieldValueChanged, true);
            app.HighPressureEditField.Value = 250;
            app.LowPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowPressureEditField.ValueChangedFcn = createCallbackFcn(app, @LowPressureEditFieldValueChanged, true);
            app.LowPressureEditField.Value = 30;
            app.mbarLabel_3 = uilabel(app.UIFigure);
            app.mbarLabel_3.Text = 'mbar';
            app.mbarLabel_4 = uilabel(app.UIFigure);
            app.mbarLabel_4.Text = 'mbar';
            app.SealPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.SealPressureEditField.ValueChangedFcn = createCallbackFcn(app, @SealPressureEditFieldValueChanged, true);
            app.SealPressureEditField.Value = -50;
            app.BreakInPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.BreakInPressureEditField.ValueChangedFcn = createCallbackFcn(app, @BreakInPressureEditFieldValueChanged, true);
            app.BreakInPressureEditField.Value = -700;
            app.mbarLabel_2 = uilabel(app.UIFigure);
            app.mbarLabel_2.Text = 'mbar';
            app.mbarLabel = uilabel(app.UIFigure);
            app.mbarLabel.Text = 'mbar';
            app.BreakInTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.BreakInTimeEditField.Value = 50;
            app.msLabel_2 = uilabel(app.UIFigure);
            app.msLabel_2.Text = 'ms';
            app.PressureLabel = uilabel(app.UIFigure);
            app.PressureLabel.Text = 'Pressure';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SolenoidControllerIndicator = uilamp(app.UIFigure);
            app.PositionsTable = uitable(app.UIFigure);
            app.PositionsTable.ColumnName = {'Safe Position'; 'Clean Position'};
            app.PositionsTable.RowName = {'Channel 1'; 'Channel 2'};
            app.SetSafeButton_Ch1 = uibutton(app.UIFigure, 'push');
            app.SetSafeButton_Ch1.ButtonPushedFcn = createCallbackFcn(app, @SetSafeButton_Ch1Pushed, true);
            app.SetSafeButton_Ch1.Text = 'Set 1 Safe';
            app.SetCleanButton_Ch1 = uibutton(app.UIFigure, 'push');
            app.SetCleanButton_Ch1.ButtonPushedFcn = createCallbackFcn(app, @SetCleanButton_Ch1Pushed, true);
            app.SetCleanButton_Ch1.Text = 'Set 1 Clean';
            app.SetSafeButton_Ch2 = uibutton(app.UIFigure, 'push');
            app.SetSafeButton_Ch2.Text = 'Set 2 Safe';
            app.SetCleanButton_Ch2 = uibutton(app.UIFigure, 'push');
            app.SetCleanButton_Ch2.Text = 'Set 2 Clean';
            app.ControllerEditFieldLabel = uilabel(app.UIFigure);
            app.ControllerEditFieldLabel.Text = 'Controller';
            app.ControllerEditField = uieditfield(app.UIFigure, 'text');
            app.ControllerEditField.Value = 'COM5';
            app.SetSafeButton_Ch3 = uibutton(app.UIFigure, 'push');
            app.SetSafeButton_Ch3.Text = 'Set 3 Safe';
            app.SetCleanButton_Ch3 = uibutton(app.UIFigure, 'push');
            app.SetCleanButton_Ch3.Text = 'Set 3 Clean';
            app.SetSafeButton_Ch4 = uibutton(app.UIFigure, 'push');
            app.SetSafeButton_Ch4.Text = 'Set 4 Safe';
            app.SetCleanButton_Ch4 = uibutton(app.UIFigure, 'push');
            app.SetCleanButton_Ch4.Text = 'Set 4 Clean';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Resistance (MOhms)')
            app.UIAxes.YLim = [1 5000];
            app.UIAxes.YScale = 'log';
            app.ElectrodeDevice_Ch1 = uieditfield(app.UIFigure, 'text');
            app.ElectrodeDevice_Ch1.Value = 'Dev2';
            app.PrimaryChannel_Ch1 = uieditfield(app.UIFigure, 'text');
            app.PrimaryChannel_Ch1.Value = 'ai0';
            app.SecondaryChannel_Ch1 = uieditfield(app.UIFigure, 'text');
            app.SecondaryChannel_Ch1.Value = 'ai1';
            app.CommandChannel_Ch1 = uieditfield(app.UIFigure, 'text');
            app.CommandChannel_Ch1.Value = 'ao0';
            app.PrimaryChannel_Ch2 = uieditfield(app.UIFigure, 'text');
            app.PrimaryChannel_Ch2.Value = 'ai2';
            app.SecondaryChannel_Ch2 = uieditfield(app.UIFigure, 'text');
            app.SecondaryChannel_Ch2.Value = 'ai3';
            app.CommandChannel_Ch2 = uieditfield(app.UIFigure, 'text');
            app.CommandChannel_Ch2.Value = 'ao1';
            app.PrimaryChannel_Ch3 = uieditfield(app.UIFigure, 'text');
            app.PrimaryChannel_Ch3.Value = 'ai4';
            app.SecondaryChannel_Ch3 = uieditfield(app.UIFigure, 'text');
            app.SecondaryChannel_Ch3.Value = 'ai5';
            app.CommandChannel_Ch3 = uieditfield(app.UIFigure, 'text');
            app.CommandChannel_Ch3.Value = 'ao2';
            app.PrimaryChannel_Ch4 = uieditfield(app.UIFigure, 'text');
            app.PrimaryChannel_Ch4.Value = 'ai6';
            app.SecondaryChannel_Ch4 = uieditfield(app.UIFigure, 'text');
            app.SecondaryChannel_Ch4.Value = 'ai7';
            app.CommandChannel_Ch4 = uieditfield(app.UIFigure, 'text');
            app.CommandChannel_Ch4.Value = 'ao3';
            app.PrimaryLabel = uilabel(app.UIFigure);
            app.PrimaryLabel.Text = 'Primary';
            app.SecondLabel = uilabel(app.UIFigure);
            app.SecondLabel.Text = 'Second';
            app.CMDLabel = uilabel(app.UIFigure);
            app.CMDLabel.Text = 'CMD';
            app.DeviceLabel = uilabel(app.UIFigure);
            app.DeviceLabel.Text = 'Device';
            app.ElectrodeDevice_Ch2 = uieditfield(app.UIFigure, 'text');
            app.ElectrodeDevice_Ch2.Value = 'Dev2';
            app.ElectrodeDevice_Ch3 = uieditfield(app.UIFigure, 'text');
            app.ElectrodeDevice_Ch3.Value = 'Dev2';
            app.ElectrodeDevice_Ch4 = uieditfield(app.UIFigure, 'text');
            app.ElectrodeDevice_Ch4.Value = 'Dev2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Autopatcher

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end