classdef AutopatcherPressureControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PatchTab                        matlab.ui.container.Tab
        Channel1ButtonGroup             matlab.ui.container.ButtonGroup
        HighPressureButton_1            matlab.ui.control.ToggleButton
        LowPressureButton_1             matlab.ui.control.ToggleButton
        BreakInButton_1                 matlab.ui.control.ToggleButton
        SealButton_1                    matlab.ui.control.ToggleButton
        AtmosphereButton_1              matlab.ui.control.ToggleButton
        BreakInPressureEditField        matlab.ui.control.NumericEditField
        SealPressureEditField           matlab.ui.control.NumericEditField
        LowPressureEditField            matlab.ui.control.NumericEditField
        HighPressureEditField           matlab.ui.control.NumericEditField
        Channel2ButtonGroup             matlab.ui.container.ButtonGroup
        HighPressureButton_2            matlab.ui.control.ToggleButton
        LowPressureButton_2             matlab.ui.control.ToggleButton
        BreakInButton_2                 matlab.ui.control.ToggleButton
        SealButton_2                    matlab.ui.control.ToggleButton
        AtmosphereButton_2              matlab.ui.control.ToggleButton
        Channel3ButtonGroup             matlab.ui.container.ButtonGroup
        HighPressureButton_3            matlab.ui.control.ToggleButton
        LowPressureButton_3             matlab.ui.control.ToggleButton
        BreakInButton_3                 matlab.ui.control.ToggleButton
        SealButton_3                    matlab.ui.control.ToggleButton
        AtmosphereButton_3              matlab.ui.control.ToggleButton
        Channel4ButtonGroup             matlab.ui.container.ButtonGroup
        HighPressureButton_4            matlab.ui.control.ToggleButton
        LowPressureButton_4             matlab.ui.control.ToggleButton
        BreakInButton_4                 matlab.ui.control.ToggleButton
        SealButton_4                    matlab.ui.control.ToggleButton
        AtmosphereButton_4              matlab.ui.control.ToggleButton
        PressureLabel                   matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        BreakInTimeEditField            matlab.ui.control.NumericEditField
        msLabel_2                       matlab.ui.control.Label
        mbarLabel                       matlab.ui.control.Label
        mbarLabel_2                     matlab.ui.control.Label
        mbarLabel_3                     matlab.ui.control.Label
        mbarLabel_4                     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ConnectedCOMChannel1            matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        ConnectedCOMChannel2            matlab.ui.control.Lamp
        Label_3                         matlab.ui.control.Label
        ConnectedCOMChannel3            matlab.ui.control.Lamp
        Label_4                         matlab.ui.control.Label
        ConnectedCOMChannel4            matlab.ui.control.Lamp
        ConnectButton_2                 matlab.ui.control.Button
        ConnectionTab                   matlab.ui.container.Tab
        SolenoidControllerEditFieldLabel  matlab.ui.control.Label
        SolenoidControllerEditField     matlab.ui.control.EditField
        ConnectButton                   matlab.ui.control.Button
        COMPortNamesLabel               matlab.ui.control.Label
        ConnectedLabel                  matlab.ui.control.Label
        CalibrateTab_2                  matlab.ui.container.Tab
        PressureRegulator1EditFieldLabel  matlab.ui.control.Label
        PressureRegulator1COMEditField  matlab.ui.control.EditField
        PressureRegulator2EditFieldLabel  matlab.ui.control.Label
        PressureRegulator2COMEditField  matlab.ui.control.EditField
        PressureRegulator3EditFieldLabel  matlab.ui.control.Label
        PressureRegulator3COMEditField  matlab.ui.control.EditField
        PressureRegulator4EditFieldLabel  matlab.ui.control.Label
        PressureRegulator4COMEditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Channel1State % State of Channel 1
        Channel2State % State of Channel 2
        Channel3State % State of Channel 3
        Channel4State % State of Channel 4
        ProportionAirModbusObjectChannel1 % Modbus Object for Channel 1
        ProportionAirModbusObjectChannel2 % Modbus Object for Channel 2
        ProportionAirModbusObjectChannel3 % Modbus Object for Channel 3
        ProportionAirModbusObjectChannel4 % Modbus Object for Channel 4
        SolenoidDriverObjectChannel % Serial Connection Object for Solenoid Driver
        modbusCMDRegister = 50 % Command Register for writing desired pressure values
        modbusServerId = 247 % Server ID for ProportionAir Modbus
    end
    
    methods (Static, Access = private)
        
        function registerValue = convertPressure(pressureValue)
            registerValue = double(floor(39.21*pressureValue + 27041));
            
            if registerValue > 65535
                registerValue = 65535;
            elseif registerValue < 0
                registerValue = 0;
            end
        end
    end
    
    methods (Access = private)
        
        function writeModbusCMD(app, COMChannel, registerValue)
            write(COMChannel, 'holdingregs', app.modbusCMDRegister, registerValue, app.modbusServerId, 'uint16');
        end
    end
    

    methods (Access = private)

        % Selection changed function: Channel1ButtonGroup
        function Channel1ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Channel1ButtonGroup.SelectedObject;

            switch selectedButton
                case app.HighPressureButton_1
                    pressure = app.HighPressureEditField.Value;
                case app.LowPressureButton_1
                    pressure = app.LowPressureEditField.Value;
                case app.AtmosphereButton_1
                    pressure = 0;
                    %also switch the atmosphere solenoid for channel 1
                case app.SealButton_1
                    pressure = app.SealPressureEditField.Value;
                case app.BreakInButton_1
                    pressure = app.BreakInPressureEditField.Value;
            end
            
            COMChannel = app.ProportionAirModbusObjectChannel1;
            registerValue = app.convertPressure(pressure);
            
            app.writeModbusCMD(COMChannel, registerValue);
        end

        % Value changed function: HighPressureEditField
        function HighPressureEditFieldValueChanged(app, event)
            pressure = app.HighPressureEditField.Value;
            registerValue = app.convertPressure(pressure);
            
            if(app.Channel1ButtonGroup.SelectedObject == app.HighPressureButton_1)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel1, registerValue);
            end
            
            if(app.Channel2ButtonGroup.SelectedObject == app.HighPressureButton_2)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel2, registerValue);
            end
            
            if(app.Channel3ButtonGroup.SelectedObject == app.HighPressureButton_3)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel3, registerValue);
            end
            
            if(app.Channel4ButtonGroup.SelectedObject == app.HighPressureButton_4)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel4, registerValue);
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            baudRate = 115200;
            parity = 'even';
            timeout = 0.1;
            
            % TRY TO CONNECT TO THE MODBUS SERVER AT THE SPECIFIED ADDRESS FOR CHANNEL 1
            try
                app.ProportionAirModbusObjectChannel1 = proportionAirModbus('serialrtu', app.PressureRegulator1COMEditField.Value, 'BaudRate', baudRate, 'Parity', parity, 'Timeout', timeout);
                app.ConnectedCOMChannel1.Color = [0 1 0];
            catch
                app.ConnectedCOMChannel1.Color = [1 0 0];
            end
            
            % TRY TO CONNECT TO THE MODBUS SERVER AT THE SPECIFIED ADDRESS FOR CHANNEL 2
            try
                app.ProportionAirModbusObjectChannel2 = proportionAirModbus('serialrtu', app.PressureRegulator2COMEditField.Value, 'BaudRate', baudRate, 'Parity', parity, 'Timeout', timeout); 
                app.ConnectedCOMChannel2.Color = [0 1 0];
            catch
                app.ConnectedCOMChannel2.Color = [1 0 0];
            end
            
            % TRY TO CONNECT TO THE MODBUS SERVER AT THE SPECIFIED ADDRESS FOR CHANNEL 3
            try
                app.ProportionAirModbusObjectChannel3  = proportionAirModbus('serialrtu', app.PressureRegulator3COMEditField.Value, 'BaudRate', baudRate, 'Parity', parity, 'Timeout', timeout);
                app.ConnectedCOMChannel3.Color = [0 1 0];
            catch
                app.ConnectedCOMChannel3.Color = [1 0 0];
            end
            
            % TRY TO CONNECT TO THE MODBUS SERVER AT THE SPECIFIED ADDRESS FOR CHANNEL 4
            try
                app.ProportionAirModbusObjectChannel4  = proportionAirModbus('serialrtu', app.PressureRegulator4COMEditField.Value, 'BaudRate', baudRate, 'Parity', parity, 'Timeout', timeout);
                app.ConnectedCOMChannel4.Color = [0 1 0];
            catch
                app.ConnectedCOMChannel4.Color = [1 0 0];
            end
        end

        % Selection changed function: Channel2ButtonGroup
        function Channel2ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Channel2ButtonGroup.SelectedObject;

            switch selectedButton
                case app.HighPressureButton_2
                    pressure = app.HighPressureEditField.Value;
                case app.LowPressureButton_2
                    pressure = app.LowPressureEditField.Value;
                case app.AtmosphereButton_2
                    pressure = 0;
                    %also switch the atmosphere solenoid for channel 1
                case app.SealButton_2
                    pressure = app.SealPressureEditField.Value;
                case app.BreakInButton_2
                    pressure = app.BreakInPressureEditField.Value;
            end
            
            COMChannel = app.ProportionAirModbusObjectChannel2;
            registerValue = app.convertPressure(pressure);
            
            app.writeModbusCMD(COMChannel, registerValue);
        end

        % Selection changed function: Channel3ButtonGroup
        function Channel3ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Channel3ButtonGroup.SelectedObject;

            switch selectedButton
                case app.HighPressureButton_3
                    pressure = app.HighPressureEditField.Value;
                case app.LowPressureButton_3
                    pressure = app.LowPressureEditField.Value;
                case app.AtmosphereButton_3
                    pressure = 0;
                    %also switch the atmosphere solenoid for channel 1
                case app.SealButton_3
                    pressure = app.SealPressureEditField.Value;
                case app.BreakInButton_3
                    pressure = app.BreakInPressureEditField.Value;
            end
            
            COMChannel = app.ProportionAirModbusObjectChannel3;
            registerValue = app.convertPressure(pressure);
            
            app.writeModbusCMD(COMChannel, registerValue);
        end

        % Selection changed function: Channel4ButtonGroup
        function Channel4ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Channel4ButtonGroup.SelectedObject;

            switch selectedButton
                case app.HighPressureButton_4
                    pressure = app.HighPressureEditField.Value;
                case app.LowPressureButton_4
                    pressure = app.LowPressureEditField.Value;
                case app.AtmosphereButton_4
                    pressure = 0;
                    %also switch the atmosphere solenoid for channel 1
                case app.SealButton_4
                    pressure = app.SealPressureEditField.Value;
                case app.BreakInButton_4
                    pressure = app.BreakInPressureEditField.Value;
            end
            
            COMChannel = app.ProportionAirModbusObjectChannel4;
            registerValue = app.convertPressure(pressure);
            
            app.writeModbusCMD(COMChannel, registerValue);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PatchTab = uitab(app.TabGroup);
            app.PatchTab.Title = 'Patch';
            app.Channel1ButtonGroup = uibuttongroup(app.PatchTab);
            app.Channel1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel1ButtonGroupSelectionChanged, true);
            app.Channel1ButtonGroup.Title = 'Channel 1';
            app.HighPressureButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.HighPressureButton_1.Text = 'High Pressure';
            app.HighPressureButton_1.Value = true;
            app.LowPressureButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.LowPressureButton_1.Text = 'Low Pressure';
            app.BreakInButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.BreakInButton_1.Text = 'Break In';
            app.SealButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.SealButton_1.Text = 'Seal';
            app.AtmosphereButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.AtmosphereButton_1.Text = 'Atmosphere';
            app.BreakInPressureEditField = uieditfield(app.PatchTab, 'numeric');
            app.BreakInPressureEditField.Value = -700;
            app.SealPressureEditField = uieditfield(app.PatchTab, 'numeric');
            app.SealPressureEditField.Value = -50;
            app.LowPressureEditField = uieditfield(app.PatchTab, 'numeric');
            app.LowPressureEditField.Value = 30;
            app.HighPressureEditField = uieditfield(app.PatchTab, 'numeric');
            app.HighPressureEditField.ValueChangedFcn = createCallbackFcn(app, @HighPressureEditFieldValueChanged, true);
            app.HighPressureEditField.Value = 250;
            app.Channel2ButtonGroup = uibuttongroup(app.PatchTab);
            app.Channel2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel2ButtonGroupSelectionChanged, true);
            app.Channel2ButtonGroup.Title = 'Channel 2';
            app.HighPressureButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.HighPressureButton_2.Text = 'High Pressure';
            app.HighPressureButton_2.Value = true;
            app.LowPressureButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.LowPressureButton_2.Text = 'Low Pressure';
            app.BreakInButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.BreakInButton_2.Text = 'Break In';
            app.SealButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.SealButton_2.Text = 'Seal';
            app.AtmosphereButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.AtmosphereButton_2.Text = 'Atmosphere';
            app.Channel3ButtonGroup = uibuttongroup(app.PatchTab);
            app.Channel3ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel3ButtonGroupSelectionChanged, true);
            app.Channel3ButtonGroup.Title = 'Channel 3';
            app.HighPressureButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.HighPressureButton_3.Text = 'High Pressure';
            app.HighPressureButton_3.Value = true;
            app.LowPressureButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.LowPressureButton_3.Text = 'Low Pressure';
            app.BreakInButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.BreakInButton_3.Text = 'Break In';
            app.SealButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.SealButton_3.Text = 'Seal';
            app.AtmosphereButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.AtmosphereButton_3.Text = 'Atmosphere';
            app.Channel4ButtonGroup = uibuttongroup(app.PatchTab);
            app.Channel4ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel4ButtonGroupSelectionChanged, true);
            app.Channel4ButtonGroup.Title = 'Channel 4';
            app.HighPressureButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.HighPressureButton_4.Text = 'High Pressure';
            app.HighPressureButton_4.Value = true;
            app.LowPressureButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.LowPressureButton_4.Text = 'Low Pressure';
            app.BreakInButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.BreakInButton_4.Text = 'Break In';
            app.SealButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.SealButton_4.Text = 'Seal';
            app.AtmosphereButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.AtmosphereButton_4.Text = 'Atmosphere';
            app.PressureLabel = uilabel(app.PatchTab);
            app.PressureLabel.Text = 'Pressure';
            app.TimeLabel = uilabel(app.PatchTab);
            app.TimeLabel.Text = 'Time';
            app.BreakInTimeEditField = uieditfield(app.PatchTab, 'numeric');
            app.BreakInTimeEditField.Value = 50;
            app.msLabel_2 = uilabel(app.PatchTab);
            app.msLabel_2.Text = 'ms';
            app.mbarLabel = uilabel(app.PatchTab);
            app.mbarLabel.Text = 'mbar';
            app.mbarLabel_2 = uilabel(app.PatchTab);
            app.mbarLabel_2.Text = 'mbar';
            app.mbarLabel_3 = uilabel(app.PatchTab);
            app.mbarLabel_3.Text = 'mbar';
            app.mbarLabel_4 = uilabel(app.PatchTab);
            app.mbarLabel_4.Text = 'mbar';
            app.Label = uilabel(app.PatchTab);
            app.Label.Text = '';
            app.ConnectedCOMChannel1 = uilamp(app.PatchTab);
            app.Label_2 = uilabel(app.PatchTab);
            app.Label_2.Text = '';
            app.ConnectedCOMChannel2 = uilamp(app.PatchTab);
            app.Label_3 = uilabel(app.PatchTab);
            app.Label_3.Text = '';
            app.ConnectedCOMChannel3 = uilamp(app.PatchTab);
            app.Label_4 = uilabel(app.PatchTab);
            app.Label_4.Text = '';
            app.ConnectedCOMChannel4 = uilamp(app.PatchTab);
            app.ConnectButton_2 = uibutton(app.PatchTab, 'push');
            app.ConnectButton_2.Text = 'Connect';
            app.ConnectionTab = uitab(app.TabGroup);
            app.ConnectionTab.Title = 'Connection';
            app.SolenoidControllerEditFieldLabel = uilabel(app.ConnectionTab);
            app.SolenoidControllerEditFieldLabel.Text = 'Solenoid Controller';
            app.SolenoidControllerEditField = uieditfield(app.ConnectionTab, 'text');
            app.SolenoidControllerEditField.Value = '/dev/cu.usbserial-XXX';
            app.ConnectButton = uibutton(app.ConnectionTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.COMPortNamesLabel = uilabel(app.ConnectionTab);
            app.COMPortNamesLabel.Text = 'COM Port Names';
            app.ConnectedLabel = uilabel(app.ConnectionTab);
            app.ConnectedLabel.Text = 'Connected';
            app.CalibrateTab_2 = uitab(app.TabGroup);
            app.CalibrateTab_2.Title = 'Calibrate';
            app.PressureRegulator1EditFieldLabel = uilabel(app.UIFigure);
            app.PressureRegulator1EditFieldLabel.Text = 'Pressure Regulator 1';
            app.PressureRegulator1COMEditField = uieditfield(app.UIFigure, 'text');
            app.PressureRegulator1COMEditField.Value = '/dev/cu.usbserial-FT3NO6ER0';
            app.PressureRegulator2EditFieldLabel = uilabel(app.UIFigure);
            app.PressureRegulator2EditFieldLabel.Text = 'Pressure Regulator 2';
            app.PressureRegulator2COMEditField = uieditfield(app.UIFigure, 'text');
            app.PressureRegulator2COMEditField.Value = '/dev/cu.usbserial-FT3NO6ER1';
            app.PressureRegulator3EditFieldLabel = uilabel(app.UIFigure);
            app.PressureRegulator3EditFieldLabel.Text = 'Pressure Regulator 3';
            app.PressureRegulator3COMEditField = uieditfield(app.UIFigure, 'text');
            app.PressureRegulator3COMEditField.Value = '/dev/cu.usbserial-FT3NO6ER2';
            app.PressureRegulator4EditFieldLabel = uilabel(app.UIFigure);
            app.PressureRegulator4EditFieldLabel.Text = 'Pressure Regulator 4';
            app.PressureRegulator4COMEditField = uieditfield(app.UIFigure, 'text');
            app.PressureRegulator4COMEditField.Value = '/dev/cu.usbserial-FT3NO6ER3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AutopatcherPressureControl

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end