classdef AutopatcherPressureControlVertical_2019b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PatchTab                        matlab.ui.container.Tab
        PressureRegulator1COMEditField  matlab.ui.control.EditField
        PressureRegulator2COMEditField  matlab.ui.control.EditField
        PressureRegulator3COMEditField  matlab.ui.control.EditField
        PressureRegulator4COMEditField  matlab.ui.control.EditField
        Channel1ButtonGroup             matlab.ui.container.ButtonGroup
        HighPressureButton_1            matlab.ui.control.ToggleButton
        LowPressureButton_1             matlab.ui.control.ToggleButton
        BreakInButton_1                 matlab.ui.control.ToggleButton
        SealButton_1                    matlab.ui.control.ToggleButton
        AtmosphereButton_1              matlab.ui.control.ToggleButton
        Channel1Label                   matlab.ui.control.Label
        Channel2ButtonGroup             matlab.ui.container.ButtonGroup
        HighPressureButton_2            matlab.ui.control.ToggleButton
        LowPressureButton_2             matlab.ui.control.ToggleButton
        BreakInButton_2                 matlab.ui.control.ToggleButton
        SealButton_2                    matlab.ui.control.ToggleButton
        AtmosphereButton_2              matlab.ui.control.ToggleButton
        Channel2Label                   matlab.ui.control.Label
        Channel3ButtonGroup             matlab.ui.container.ButtonGroup
        HighPressureButton_3            matlab.ui.control.ToggleButton
        LowPressureButton_3             matlab.ui.control.ToggleButton
        BreakInButton_3                 matlab.ui.control.ToggleButton
        SealButton_3                    matlab.ui.control.ToggleButton
        AtmosphereButton_3              matlab.ui.control.ToggleButton
        Channel3Label                   matlab.ui.control.Label
        Channel4ButtonGroup             matlab.ui.container.ButtonGroup
        HighPressureButton_4            matlab.ui.control.ToggleButton
        LowPressureButton_4             matlab.ui.control.ToggleButton
        BreakInButton_4                 matlab.ui.control.ToggleButton
        SealButton_4                    matlab.ui.control.ToggleButton
        AtmosphereButton_4              matlab.ui.control.ToggleButton
        Channel4Label                   matlab.ui.control.Label
        ConnectedCOMChannel1            matlab.ui.control.Lamp
        ConnectedCOMChannel2            matlab.ui.control.Lamp
        ConnectedCOMChannel3            matlab.ui.control.Lamp
        ConnectedCOMChannel4            matlab.ui.control.Lamp
        HighPressureEditField           matlab.ui.control.NumericEditField
        LowPressureEditField            matlab.ui.control.NumericEditField
        mbarLabel_3                     matlab.ui.control.Label
        mbarLabel_4                     matlab.ui.control.Label
        SealPressureEditField           matlab.ui.control.NumericEditField
        BreakInPressureEditField        matlab.ui.control.NumericEditField
        mbarLabel_2                     matlab.ui.control.Label
        mbarLabel                       matlab.ui.control.Label
        BreakInTimeEditField            matlab.ui.control.NumericEditField
        msLabel_2                       matlab.ui.control.Label
        PressureLabel                   matlab.ui.control.Label
        SolenoidControllerEditFieldLabel  matlab.ui.control.Label
        SolenoidControllerEditField     matlab.ui.control.EditField
        ConnectButton                   matlab.ui.control.Button
        SolenoidControllerIndicator     matlab.ui.control.Lamp
        CalibrateTab_2                  matlab.ui.container.Tab
        ArdunioResponseEditFieldLabel   matlab.ui.control.Label
        ArdunioResponseEditField        matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Channel1State % State of Channel 1
        Channel2State % State of Channel 2
        Channel3State % State of Channel 3
        Channel4State %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED % State of Channel 4
        ProportionAirModbusObjectChannel1 % Modbus Object for Channel 1
        ProportionAirModbusObjectChannel2 % Modbus Object for Channel 2
        ProportionAirModbusObjectChannel3 % Modbus Object for Channel 3
        ProportionAirModbusObjectChannel4 % Modbus Object for Channel 4
        SolenoidDriverObjectChannel % Serial Connection Object for Solenoid Driver
        modbusCMDRegister = 50 % Command Register for writing desired pressure values
        modbusPressureRegister = 49 % Register that holds the current internal pressure measurement value
        modbusServerId = 247 % Server ID for ProportionAir Modbus
        baudRate = 115200;
        parity = 'even';
        timeout = 0.1;
    end
    
    methods (Static, Access = private)
        
        function registerValue = convertPressure(pressureValue)
            registerValue = double(floor(39.21*pressureValue + 27041));
            
            if registerValue > 65535
                registerValue = 65535;
            elseif registerValue < 0
                registerValue = 0;
            end
        end


        function closeAndClearAllConnectionsToPort(serialPortName)
            %close and clear all connections to the serial port
            openPorts = instrfind('Port', serialPortName, 'Status', 'open');
            if ~isempty(openPorts)
                fclose(openPorts);
            end
            delete(instrfind('Port', serialPortName)); 
        end
    end


    methods (Access = private)
        
        function status = writeModbusCMD(app, COMChannel, registerValue)  
            try
                write(COMChannel, 'holdingregs', app.modbusCMDRegister, registerValue, app.modbusServerId, 'uint16');
                
                if COMChannel == app.ProportionAirModbusObjectChannel1
                    app.ConnectedCOMChannel1.Color = [0 1 0];
                elseif COMChannel == app.ProportionAirModbusObjectChannel2
                    app.ConnectedCOMChannel2.Color = [0 1 0];
                elseif COMChannel == app.ProportionAirModbusObjectChannel3
                    app.ConnectedCOMChannel3.Color = [0 1 0];    
                elseif COMChannel == app.ProportionAirModbusObjectChannel4
                    app.ConnectedCOMChannel4.Color = [0 1 0];
                end
                
                status = true;
            
            catch
                if COMChannel == app.ProportionAirModbusObjectChannel1
                    app.ConnectedCOMChannel1.Color = [1 0 0];
                elseif COMChannel == app.ProportionAirModbusObjectChannel2
                    app.ConnectedCOMChannel2.Color = [1 0 0];
                elseif COMChannel == app.ProportionAirModbusObjectChannel3
                    app.ConnectedCOMChannel3.Color = [1 0 0];    
                elseif COMChannel == app.ProportionAirModbusObjectChannel4
                    app.ConnectedCOMChannel4.Color = [1 0 0];
                end
                
                status = false;
            end
        end


        function m = connectToModbus(app, COMChannel)
                
            m = proportionAirModbus('serialrtu', COMChannel, 'BaudRate', app.baudRate, 'Parity', app.parity, 'Timeout', app.timeout);
        end


        function arduinoWrite(app, CMD)
           fwrite(app.SolenoidDriverObjectChannel, CMD);
           
           set(app.ArdunioResponseEditField, 'Value', fgetl(app.SolenoidDriverObjectChannel));
        end


        function registerValue = readModbusPressure(app, COMChannel)
            registerValue = read(COMChannel,'holdingregs', app.modbusPressureRegister, 1, app.modbusServerId, 'uint16');
        end


        function results = genericChannelButtonGroupSelectionChanged(app, channel)
            % need to make generic channel button group selection changed handler
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Selection changed function: Channel1ButtonGroup
        function Channel1ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Channel1ButtonGroup.SelectedObject;

            switch selectedButton
                case app.HighPressureButton_1
                    pressure = app.HighPressureEditField.Value;
                    switchString = 'pressure 1';
                case app.LowPressureButton_1
                    pressure = app.LowPressureEditField.Value;
                    switchString = 'pressure 1';
                case app.AtmosphereButton_1
                    pressure = 0;
                    switchString = 'atm 1';
                case app.SealButton_1
                    pressure = app.SealPressureEditField.Value;
                    switchString = 'pressure 1';
                case app.BreakInButton_1
                    if app.Channel1State ~= app.AtmosphereButton_1
                        % switch to atmosphere if that wasn't the previous
                        % state
                        pressure = 0;
                        switchString = 'atm 1';
                        selectedButton = app.AtmosphereButton_1;
                    else
                        % if atmosphere was the previous state
                        pressure = app.BreakInPressureEditField.Value;
                        switchString = ['breakin 1 ' num2str(app.BreakInTimeEditField.Value)];
                    end
            end
            
            COMChannel = app.ProportionAirModbusObjectChannel1;
            registerValue = app.convertPressure(pressure);
            
            if app.writeModbusCMD(COMChannel, registerValue)
                pause(.1);
                app.arduinoWrite(switchString);
                if strcmp(switchString(1:3), 'bre') 
                    pause(app.BreakInTimeEditField.Value/1000); %wait for the break-in time
                    app.Channel1State = app.AtmosphereButton_1; %set the state back to atmosphere
                else
                    app.Channel1State = selectedButton;
                end
            end
            app.Channel1ButtonGroup.set('SelectedObject', app.Channel1State); %revert the button group to the previous state on error
        end

        % Value changed function: HighPressureEditField
        function HighPressureEditFieldValueChanged(app, event)
            pressure = app.HighPressureEditField.Value;
            registerValue = app.convertPressure(pressure);
            
            if(app.Channel1ButtonGroup.SelectedObject == app.HighPressureButton_1)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel1, registerValue);
            end
            
            if(app.Channel2ButtonGroup.SelectedObject == app.HighPressureButton_2)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel2, registerValue);
            end
            
            if(app.Channel3ButtonGroup.SelectedObject == app.HighPressureButton_3)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel3, registerValue);
            end
            
            if(app.Channel4ButtonGroup.SelectedObject == app.HighPressureButton_4)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel4, registerValue);
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)

            app.Channel1State = app.HighPressureButton_1; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
            app.Channel2State = app.HighPressureButton_2; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
            app.Channel3State = app.HighPressureButton_3; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
            app.Channel4State = app.HighPressureButton_4; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
            
            % blank all of the lamps so we know when things have changed
            app.ConnectedCOMChannel1.Color = [0.47,0.67,0.19];
            app.ConnectedCOMChannel2.Color = [0.47,0.67,0.19];
            app.ConnectedCOMChannel3.Color = [0.47,0.67,0.19];
            app.ConnectedCOMChannel4.Color = [0.47,0.67,0.19];
            app.SolenoidControllerIndicator.Color = [0.47,0.67,0.19];
            
            % TRY TO CONNECT TO THE MODBUS SERVER AT THE SPECIFIED ADDRESS FOR CHANNEL 1
            try
                serialPortName = app.PressureRegulator1COMEditField.Value;
                
                app.closeAndClearAllConnectionsToPort(serialPortName);
                
                app.ProportionAirModbusObjectChannel1 = app.connectToModbus(serialPortName);
                app.readModbusPressure(app.ProportionAirModbusObjectChannel1);
                app.Channel1State = app.HighPressureButton_1; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
                app.ConnectedCOMChannel1.Color = [0 1 0];
            catch error
                disp(error);
                app.ConnectedCOMChannel1.Color = [1 0 0];
            end
            
            % TRY TO CONNECT TO THE MODBUS SERVER AT THE SPECIFIED ADDRESS FOR CHANNEL 2
            try
                serialPortName = app.PressureRegulator2COMEditField.Value;
                
                app.closeAndClearAllConnectionsToPort(serialPortName);
                
                app.ProportionAirModbusObjectChannel2 = app.connectToModbus(serialPortName);
                app.readModbusPressure(app.ProportionAirModbusObjectChannel2);
                app.Channel2State = app.HighPressureButton_2; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
                app.ConnectedCOMChannel2.Color = [0 1 0];
            catch
                app.ConnectedCOMChannel2.Color = [1 0 0];
            end
            
            % TRY TO CONNECT TO THE MODBUS SERVER AT THE SPECIFIED ADDRESS FOR CHANNEL 3
            try
                serialPortName = app.PressureRegulator3COMEditField.Value;
                
                app.closeAndClearAllConnectionsToPort(serialPortName);
                
                app.ProportionAirModbusObjectChannel3 = app.connectToModbus(serialPortName);
                app.readModbusPressure(app.ProportionAirModbusObjectChannel3);
                app.Channel3State = app.HighPressureButton_3; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
                app.ConnectedCOMChannel3.Color = [0 1 0];
            catch
                app.ConnectedCOMChannel3.Color = [1 0 0];
            end
            
            % TRY TO CONNECT TO THE MODBUS SERVER AT THE SPECIFIED ADDRESS FOR CHANNEL 4
            try
                serialPortName = app.PressureRegulator4COMEditField.Value;
                
                app.closeAndClearAllConnectionsToPort(serialPortName);
                
                app.ProportionAirModbusObjectChannel4 = app.connectToModbus(serialPortName);
                app.readModbusPressure(app.ProportionAirModbusObjectChannel4);
                app.Channel4State = app.HighPressureButton_4; %TODO: THIS SHOULD ACTUALLY TRY TO READ THE CURRENT PRESSURE AND GUESS WHICH BUTTON SHOULD BE SELECTED
                app.ConnectedCOMChannel4.Color = [0 1 0];
            catch
                app.ConnectedCOMChannel4.Color = [1 0 0];
            end
            
            % TRY TO CONNECT TO THE ARDUINO
            try
                serialPortName = app.SolenoidControllerEditField.Value;
                
                app.closeAndClearAllConnectionsToPort(serialPortName);
                
                % create a connection to the serial port
                app.SolenoidDriverObjectChannel = serial(serialPortName, 'BaudRate', app.baudRate, 'Terminator', 'LF');
                fopen(app.SolenoidDriverObjectChannel);
                app.SolenoidControllerIndicator.Color = [0 1 0];
            catch error
                app.SolenoidControllerIndicator.Color = [1 0 0];
                %rethrow(error);
            end
        end

        % Selection changed function: Channel2ButtonGroup
        function Channel2ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Channel2ButtonGroup.SelectedObject;

            switch selectedButton
                case app.HighPressureButton_2
                    pressure = app.HighPressureEditField.Value;
                    switchString = 'pressure 2';
                case app.LowPressureButton_2
                    pressure = app.LowPressureEditField.Value;
                    switchString = 'pressure 2';
                case app.AtmosphereButton_2
                    pressure = 0;
                    switchString = 'atm 2';
                case app.SealButton_2
                    pressure = app.SealPressureEditField.Value;
                    switchString = 'pressure 2';
                case app.BreakInButton_2
                    pressure = app.BreakInPressureEditField.Value;
                    switchString = ['breakin 2 ' num2str(app.BreakInTimeEditField.Value)];
            end
            
            COMChannel = app.ProportionAirModbusObjectChannel2;
            registerValue = app.convertPressure(pressure);
            
            if(app.writeModbusCMD(COMChannel, registerValue))
                pause(.1);
                app.arduinoWrite(switchString);
                if strcmp(switchString(1:3), 'bre')
                    % wait for return from arduino
                    %pause(app.BreakInTimeEditField.Value/1000);
                    app.Channel2ButtonGroup.set('SelectedObject', app.AtmosphereButton_2); %revert button group to atmosphere
                else
                    app.Channel2State = selectedButton;
                end
            else
                app.Channel2ButtonGroup.set('SelectedObject', app.Channel2State); %revert the button group to the previous state on error
            end
        end

        % Selection changed function: Channel3ButtonGroup
        function Channel3ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Channel3ButtonGroup.SelectedObject;

            switch selectedButton
                case app.HighPressureButton_3
                    pressure = app.HighPressureEditField.Value;
                    switchString = 'pressure 3';
                case app.LowPressureButton_3
                    pressure = app.LowPressureEditField.Value;
                    switchString = 'pressure 3';
                case app.AtmosphereButton_3
                    pressure = 0;
                    switchString = 'atm 3';
                case app.SealButton_3
                    pressure = app.SealPressureEditField.Value;
                    switchString = 'pressure 3';
                case app.BreakInButton_3
                    pressure = app.BreakInPressureEditField.Value;
                    switchString = ['breakin 3 ' num2str(app.BreakInTimeEditField.Value)];
            end
            
            COMChannel = app.ProportionAirModbusObjectChannel3;
            registerValue = app.convertPressure(pressure);
            
            if app.writeModbusCMD(COMChannel, registerValue)
                pause(.1);
                app.arduinoWrite(switchString);
                if strcmp(switchString(1:3), 'bre') 
                    pause(app.BreakInTimeEditField.Value/1000);
                    app.Channel3ButtonGroup.set('SelectedObject', app.AtmosphereButton_3); %revert button group to atmosphere
                else
                    app.Channel3State = selectedButton;
                end
            else
                app.Channel3ButtonGroup.set('SelectedObject', app.Channel3State); %revert the button group to the previous state on error
            end
        end

        % Selection changed function: Channel4ButtonGroup
        function Channel4ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Channel4ButtonGroup.SelectedObject;

            switch selectedButton
                case app.HighPressureButton_4
                    pressure = app.HighPressureEditField.Value;
                    switchString = 'pressure 4';
                case app.LowPressureButton_4
                    pressure = app.LowPressureEditField.Value;
                    switchString = 'pressure 4';
                case app.AtmosphereButton_4
                    pressure = 0;
                    switchString = 'atm 4';
                case app.SealButton_4
                    pressure = app.SealPressureEditField.Value;
                    switchString = 'pressure 4';
                case app.BreakInButton_4
                    pressure = app.BreakInPressureEditField.Value;
                    switchString = ['breakin 4 ' num2str(app.BreakInTimeEditField.Value)];
            end
            
            COMChannel = app.ProportionAirModbusObjectChannel4;
            registerValue = app.convertPressure(pressure);
            
            if app.writeModbusCMD(COMChannel, registerValue)
                pause(.1);
                app.arduinoWrite(switchString);
                if strcmp(switchString(1:3), 'bre') 
                    pause(app.BreakInTimeEditField.Value/1000);
                    app.Channel4ButtonGroup.set('SelectedObject', app.AtmosphereButton_4); %revert button group to atmosphere
                else
                    app.Channel4State = selectedButton;
                end
            else
                disp(app.Channel4State);
                %app.Channel4ButtonGroup.set('SelectedObject', app.Channel4State); %revert the button group to the previous state on error
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                fwrite(app.SolenoidDriverObjectChannel, 'atm 1');
                fwrite(app.SolenoidDriverObjectChannel, 'atm 2');
                fwrite(app.SolenoidDriverObjectChannel, 'atm 3');
                fwrite(app.SolenoidDriverObjectChannel, 'atm 4');
                
                fclose(app.SolenoidDriverObjectChannel);
            end
            
            clear app.ProportionAirModbusObjectChannel1 app.ProportionAirModbusObjectChannel2 app.ProportionAirModbusObjectChannel3 app.ProportionAirModbusObjectChannel4 app.SolenoidDriverObjectChannel;
            delete(app)
            
        end

        % Value changed function: LowPressureEditField
        function LowPressureEditFieldValueChanged(app, event)
            pressure = app.LowPressureEditField.Value;
            registerValue = app.convertPressure(pressure);
            
            if(app.Channel1ButtonGroup.SelectedObject == app.LowPressureButton_1)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel1, registerValue);
            end
            
            if(app.Channel2ButtonGroup.SelectedObject == app.LowPressureButton_2)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel2, registerValue);
            end
            
            if(app.Channel3ButtonGroup.SelectedObject == app.LowPressureButton_3)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel3, registerValue);
            end
            
            if(app.Channel4ButtonGroup.SelectedObject == app.LowPressureButton_4)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel4, registerValue);
            end
        end

        % Value changed function: SealPressureEditField
        function SealPressureEditFieldValueChanged(app, event)
            pressure = app.SealPressureEditField.Value;
            registerValue = app.convertPressure(pressure);
            
            if(app.Channel1ButtonGroup.SelectedObject == app.SealButton_1)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel1, registerValue);
            end
            
            if(app.Channel2ButtonGroup.SelectedObject == app.SealButton_2)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel2, registerValue);
            end
            
            if(app.Channel3ButtonGroup.SelectedObject == app.SealButton_3)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel3, registerValue);
            end
            
            if(app.Channel4ButtonGroup.SelectedObject == app.SealButton_4)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel4, registerValue);
            end
        end

        % Value changed function: BreakInPressureEditField
        function BreakInPressureEditFieldValueChanged(app, event)
            pressure = app.BreakInPressureEditField.Value;
            registerValue = app.convertPressure(pressure);
            
            if(app.Channel1ButtonGroup.SelectedObject == app.BreakInButton_1)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel1, registerValue);
            end
            
            if(app.Channel2ButtonGroup.SelectedObject == app.BreakInButton_2)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel2, registerValue);
            end
            
            if(app.Channel3ButtonGroup.SelectedObject == app.BreakInButton_3)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel3, registerValue);
            end
            
            if(app.Channel4ButtonGroup.SelectedObject == app.BreakInButton_4)
               app.writeModbusCMD(app.ProportionAirModbusObjectChannel4, registerValue);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PatchTab = uitab(app.TabGroup);
            app.PatchTab.Title = 'Patch';
            app.PressureRegulator1COMEditField = uieditfield(app.PatchTab, 'text');
            app.PressureRegulator1COMEditField.Value = '/dev/cu.usbserial-FT3NO6ER0';
            app.PressureRegulator2COMEditField = uieditfield(app.PatchTab, 'text');
            app.PressureRegulator2COMEditField.Value = '/dev/cu.usbserial-FT3NO6ER1';
            app.PressureRegulator3COMEditField = uieditfield(app.PatchTab, 'text');
            app.PressureRegulator3COMEditField.Value = '/dev/cu.usbserial-FT3NO6ER2';
            app.PressureRegulator4COMEditField = uieditfield(app.PatchTab, 'text');
            app.PressureRegulator4COMEditField.Value = '/dev/cu.usbserial-FT3NO6ER3';
            app.Channel1ButtonGroup = uibuttongroup(app.PatchTab);
            app.Channel1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel1ButtonGroupSelectionChanged, true);
            app.HighPressureButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.HighPressureButton_1.Text = 'High Pressure';
            app.HighPressureButton_1.Value = true;
            app.LowPressureButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.LowPressureButton_1.Text = 'Low Pressure';
            app.BreakInButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.BreakInButton_1.Text = 'Break In';
            app.SealButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.SealButton_1.Text = 'Seal';
            app.AtmosphereButton_1 = uitogglebutton(app.Channel1ButtonGroup);
            app.AtmosphereButton_1.Text = 'Atmosphere';
            app.Channel1Label = uilabel(app.PatchTab);
            app.Channel1Label.Text = 'Channel 1';
            app.Channel2ButtonGroup = uibuttongroup(app.PatchTab);
            app.Channel2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel2ButtonGroupSelectionChanged, true);
            app.HighPressureButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.HighPressureButton_2.Text = 'High Pressure';
            app.HighPressureButton_2.Value = true;
            app.LowPressureButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.LowPressureButton_2.Text = 'Low Pressure';
            app.BreakInButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.BreakInButton_2.Text = 'Break In';
            app.SealButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.SealButton_2.Text = 'Seal';
            app.AtmosphereButton_2 = uitogglebutton(app.Channel2ButtonGroup);
            app.AtmosphereButton_2.Text = 'Atmosphere';
            app.Channel2Label = uilabel(app.PatchTab);
            app.Channel2Label.Text = 'Channel 2';
            app.Channel3ButtonGroup = uibuttongroup(app.PatchTab);
            app.Channel3ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel3ButtonGroupSelectionChanged, true);
            app.HighPressureButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.HighPressureButton_3.Text = 'High Pressure';
            app.HighPressureButton_3.Value = true;
            app.LowPressureButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.LowPressureButton_3.Text = 'Low Pressure';
            app.BreakInButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.BreakInButton_3.Text = 'Break In';
            app.SealButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.SealButton_3.Text = 'Seal';
            app.AtmosphereButton_3 = uitogglebutton(app.Channel3ButtonGroup);
            app.AtmosphereButton_3.Text = 'Atmosphere';
            app.Channel3Label = uilabel(app.PatchTab);
            app.Channel3Label.Text = 'Channel 3';
            app.Channel4ButtonGroup = uibuttongroup(app.PatchTab);
            app.Channel4ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Channel4ButtonGroupSelectionChanged, true);
            app.HighPressureButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.HighPressureButton_4.Text = 'High Pressure';
            app.HighPressureButton_4.Value = true;
            app.LowPressureButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.LowPressureButton_4.Text = 'Low Pressure';
            app.BreakInButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.BreakInButton_4.Text = 'Break In';
            app.SealButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.SealButton_4.Text = 'Seal';
            app.AtmosphereButton_4 = uitogglebutton(app.Channel4ButtonGroup);
            app.AtmosphereButton_4.Text = 'Atmosphere';
            app.Channel4Label = uilabel(app.PatchTab);
            app.Channel4Label.Text = 'Channel 4';
            app.ConnectedCOMChannel1 = uilamp(app.PatchTab);
            app.ConnectedCOMChannel2 = uilamp(app.PatchTab);
            app.ConnectedCOMChannel3 = uilamp(app.PatchTab);
            app.ConnectedCOMChannel4 = uilamp(app.PatchTab);
            app.HighPressureEditField = uieditfield(app.PatchTab, 'numeric');
            app.HighPressureEditField.ValueChangedFcn = createCallbackFcn(app, @HighPressureEditFieldValueChanged, true);
            app.HighPressureEditField.Value = 250;
            app.LowPressureEditField = uieditfield(app.PatchTab, 'numeric');
            app.LowPressureEditField.ValueChangedFcn = createCallbackFcn(app, @LowPressureEditFieldValueChanged, true);
            app.LowPressureEditField.Value = 30;
            app.mbarLabel_3 = uilabel(app.PatchTab);
            app.mbarLabel_3.Text = 'mbar';
            app.mbarLabel_4 = uilabel(app.PatchTab);
            app.mbarLabel_4.Text = 'mbar';
            app.SealPressureEditField = uieditfield(app.PatchTab, 'numeric');
            app.SealPressureEditField.ValueChangedFcn = createCallbackFcn(app, @SealPressureEditFieldValueChanged, true);
            app.SealPressureEditField.Value = -50;
            app.BreakInPressureEditField = uieditfield(app.PatchTab, 'numeric');
            app.BreakInPressureEditField.ValueChangedFcn = createCallbackFcn(app, @BreakInPressureEditFieldValueChanged, true);
            app.BreakInPressureEditField.Value = -700;
            app.mbarLabel_2 = uilabel(app.PatchTab);
            app.mbarLabel_2.Text = 'mbar';
            app.mbarLabel = uilabel(app.PatchTab);
            app.mbarLabel.Text = 'mbar';
            app.BreakInTimeEditField = uieditfield(app.PatchTab, 'numeric');
            app.BreakInTimeEditField.Value = 50;
            app.msLabel_2 = uilabel(app.PatchTab);
            app.msLabel_2.Text = 'ms';
            app.PressureLabel = uilabel(app.PatchTab);
            app.PressureLabel.Text = 'Pressure';
            app.SolenoidControllerEditFieldLabel = uilabel(app.PatchTab);
            app.SolenoidControllerEditFieldLabel.Text = 'Solenoid Controller';
            app.SolenoidControllerEditField = uieditfield(app.PatchTab, 'text');
            app.SolenoidControllerEditField.Value = '/dev/cu.usbmodem3201';
            app.ConnectButton = uibutton(app.PatchTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SolenoidControllerIndicator = uilamp(app.PatchTab);
            app.CalibrateTab_2 = uitab(app.TabGroup);
            app.CalibrateTab_2.Title = 'Calibrate';
            app.ArdunioResponseEditFieldLabel = uilabel(app.UIFigure);
            app.ArdunioResponseEditFieldLabel.Text = 'Ardunio Response';
            app.ArdunioResponseEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutopatcherPressureControlVertical_2019b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end